classdef SCFit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SCFitUIFigure               matlab.ui.Figure
        OpenAxes                    matlab.ui.control.UIAxes
        ShutAxes                    matlab.ui.control.UIAxes
        LoadandPreprocessDataPanel  matlab.ui.container.Panel
        LoadIdealizedButton         matlab.ui.control.Button
        ResolutionsPanel            matlab.ui.container.Panel
        ShutLabel                   matlab.ui.control.Label
        ShutResms                   matlab.ui.control.NumericEditField
        OpenLabel                   matlab.ui.control.Label
        OpenResms                   matlab.ui.control.NumericEditField
        ResolutionSwitch            matlab.ui.control.Switch
        ConcatenateDwellsPanel      matlab.ui.container.Panel
        ConcatenatedSwitch          matlab.ui.control.Switch
        ClearIdealized              matlab.ui.control.Button
        LoadandFitModelPanel        matlab.ui.container.Panel
        LoadModel                   matlab.ui.control.Button
        FitButton                   matlab.ui.control.Button
        ViewModel                   matlab.ui.control.Button
        RndModel                    matlab.ui.control.Button
        ClearModel                  matlab.ui.control.Button
        DataFileLabel               matlab.ui.control.Label
        DataFile                    matlab.ui.control.EditField
        StatusPanel                 matlab.ui.container.Panel
        StatusLabel                 matlab.ui.control.Label
        SaveResultsBtn              matlab.ui.control.Button
        ModelFileLabel              matlab.ui.control.Label
        ModelFile                   matlab.ui.control.EditField
        IftimefreecsventerthesampleintervalEditFieldLabel  matlab.ui.control.Label
        si                          matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        amplitudes  % Array of idealized single channel amplitudes
        durations   % Array of idealized single channel dwell times
        resolvedDwells  % Array of resolved idealized dwell times
        resolvedStates  % Array of resolved idealized amplitudes
        DWTFILE  % logical flag indicating if idealized dwells were from a QuB .dwt file or not.
        CSVFILE  % logical flag indicating if idealized dwells were from a csv file or not. MOSTLY equiv to dwt file.
        SCNFILE % logical flag indicating if idealized dwells were from a SCAN/SCN file or not.
        fittedRates  % Rates from hjcfit
        LL  % Log likelihood from hjcfit
        qnew  % New Q matrix after fitting
        hessian  % Hessian matrix of parameters from hjcfit
        covarmat  % Covariance matrix of parameter estimations from hjcfit
        corrmat  % Correlation matrix of parameters from hjcfit
        history  % history of fitting iterations from hjcfit
        q  % Q matrix
        A  % Indices in q of the open states of the model
        F  % Indices in q of the shut states of the model
        idxall  % Indices of all rates in q
        gamma  % Matrix of linear constraints on all the rates in Q
        xi  % Column vector indicating the equalities for the linear constraints in gamma
        idxvary  % Indices in q of rates that will vary
        idxconstrain  % Indices in q of rates that will be constrained
        runtime  % how long it took the fitting algorithm to complete
        nRnd  % counter for how many times the model rates have been randomized
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadIdealizedButton
        function LoadIdealizedButtonPushed(app, event)

            [file, path, filterIndex] = uigetfile({'*.dwt','QuB Idealized Data';'*.scn','SCAN idealized data';'*.csv','comma-delimited text';'*.txt','other textfiles'},'MultiSelect','off');
            app.DWTFILE=false;
            app.CSVFILE=false;
            app.SCNFILE=false;
            if file == 0
                return
            end
            [path,file,ext] = fileparts(file);
            if ext==".dwt"
                app.DWTFILE=true;
            elseif ext==".scn"
                app.SCNFILE=true;
                app.DWTFILE=false;
            else
                %presumably then textfile/csv
                app.CSVFILE=true;
            end
            app.DWTFILE=false;
            
            % Read idealized data
            if app.DWTFILE
                [app.durations, app.amplitudes] = dwtread([path file]);
                if isinteger(app.amplitudes)
                    app.amplitudes=double(app.amplitudes);
                end
                
                % Disable concatenate dwells
                app.ConcatenatedSwitch.Enable = 'off';
                app.ConcatenatedSwitch.Value = 'Off';
            elseif app.SCNFILE
                [app.durations, app.amplitudes, ~, cal] = scanread([path file]);
                app.amplitudes=app.amplitudes*cal;
            else
                csvsi=app.si.Value
                [app.durations, app.amplitudes] = scfitcsvread([path file],csvsi);
                %for many features csv will be like dwt so set this now,
                %confusingly
                app.DWTFILE=true;
            end
            
            plothist(app.durations, app.amplitudes, 0.05, 0.1, [app.OpenAxes, app.ShutAxes], true);
            app.DataFile.Value = [path, file];
        end

        % Value changed function: OpenResms
        function OpenResmsValueChanged(app, event)
            % hjcfit() currently supports only one dead time, so enforce this in UI
            app.ShutResms.Value = event.Value;
        end

        % Value changed function: ResolutionSwitch
        function ImposeResButtonPushed(app, event)
            if strcmp(event.EventName, 'ValueChanged')
                if strcmp(event.Value, 'On')
                    if app.OpenResms.Value == 0 && app.ShutResms.Value == 0
                        msg = sprintf('The open and shut time resolutions are both 0, hence there''s nothing to do');
                        uialert(app.SCFitUIFigure, msg, 'Warning', 'Icon', 'warning');
                        event.Source.Value = 'Off';
                        return
                    else
                        [app.resolvedDwells, app.resolvedStates] = imposeres (app.durations, app.amplitudes, app.OpenResms.Value, app.ShutResms.Value);
                        if numel(app.resolvedDwells) < 2
                            msg = sprintf('Only a single duration was resolved. Did you enter the resolution in milliseconds? Are the durations in milliseconds?');
                            uialert(app.SCFitUIFigure, msg, 'Error', 'Icon', 'error');
                            return
                        else 
                            plothist(app.resolvedDwells, app.resolvedStates, 0.05, 0.1, [app.OpenAxes, app.ShutAxes], true);
                        end
                    end
                    if ~app.DWTFILE 
                        app.ConcatenatedSwitch.Enable = 'on';
                    end
                else
                    app.ConcatenatedSwitch.Enable = 'off';
                    app.ConcatenatedSwitch.Value = 'Off';
                    app.resolvedDwells = [];
                    app.resolvedStates = [];
                    plothist(app.durations, app.amplitudes, 0.05, 0.1, [app.OpenAxes, app.ShutAxes], true);
                end
            end
        end

        % Button pushed function: LoadModel
        function LoadModelButtonPushed(app, event)
            app.StatusLabel.Text = "Loading Model ...";
            prevColor = app.StatusLabel.FontColor;
            app.StatusLabel.FontColor = 'red';
            
            [app.q, app.A, app.F, app.idxall, app.gamma, app.xi, app.idxvary, app.idxconstrain, modelFile] = loadmodel;
            
            app.StatusLabel.Text = "Ready";
            app.StatusLabel.FontColor = prevColor;
            app.ModelFile.Value = modelFile;
            app.nRnd = 0;
            
            % Show the pdf, if there are resolved dwells
            if ~isempty(app.resolvedDwells)
                qout = app.q;
                qout = qout - diag(sum(qout,2));
                plotqhist (qout, app.A, app.F, app.resolvedDwells(app.resolvedStates ~= 0), app.resolvedDwells(app.resolvedStates == 0), app.OpenResms.Value);
            end
        end

        % Value changed function: ConcatenatedSwitch
        function ConcatenateButtonPushed(app, event)
            if strcmp(event.EventName, 'ValueChanged')
                if strcmp(event.Value, 'On')
                    % Concatenate dwell times into contiguous open periods
                    % Needed for SCN data, but not for DWT
                    zeroAmp = 0;
                    concatAmp = 2*max(abs(app.resolvedStates));
                    [app.resolvedDwells, app.resolvedStates] = concatdwells(app.resolvedDwells, app.resolvedStates, concatAmp, zeroAmp);
                    plothist(app.resolvedDwells, app.resolvedStates, 0.05, 0.1, [app.OpenAxes, app.ShutAxes], true);
                else
                    if app.OpenResms.Value == 0 && app.ShutResms.Value == 0
                        msg = sprintf('The open and shut time resolutions are both 0, hence there''s nothing to do');
                        uialert(app.SCFitUIFigure, msg, 'Warning', 'Icon', 'warning');
                        return
                    else
                        [app.resolvedDwells, app.resolvedStates] = imposeres (app.durations, app.amplitudes, app.OpenResms.Value, app.ShutResms.Value);
                        if numel(app.resolvedDwells) < 2
                            msg = sprintf('Only a single duration was resolved. Did you enter the resolution in milliseconds? Are the durations in milliseconds?');
                            uialert(app.SCFitUIFigure, msg, 'Error', 'Icon', 'error');
                            return
                        else 
                            plothist(app.resolvedDwells, app.resolvedStates, 0.05, 0.1, [app.OpenAxes, app.ShutAxes], true);
                        end
                    end
                end
            end
        end

        % Button pushed function: FitButton
        function FitButtonPushed(app, event)
            % Make sure idealization starts with opening and ends with a closing
            if strcmp(app.ResolutionSwitch.Value, 'Off')
                uialert(app.SCFitUIFigure, 'Impose a resolution first.', 'Error', 'Icon', 'error');
                return
            end
            if ~app.DWTFILE && strcmp(app.ConcatenatedSwitch, 'Off')
                uialert(app.SCFitUIFigure, 'Concatenate dwells before fitting the rates', 'Error', 'Icon', 'error')
                return
            end
            if(app.resolvedStates(1)==0)
                app.resolvedDwells(1)=[];
                app.resolvedStates(1)=[];
            end
            if(app.resolvedStates(end)~=0)
                app.resolvedDwells(end)=[];
                app.resolvedStates(end)=[];
            end
            
            if ~mod(numel(app.resolvedDwells),2)==0
                msg = sprintf(['There must be an even number of resolved transitions, but there were %d.\n'], numel(app.resolvedDwells));
                uialert(app.SCFitUIFigure, msg, 'Error', 'Icon', 'error');
                return
            end
            
            % Let the user know what's going on
            app.StatusLabel.Text = "Fitting Model Rates ...";
            prevColor = app.StatusLabel.FontColor;
            app.StatusLabel.FontColor = 'red';
            % gray out UI components
            app.LoadIdealizedButton.Enable = 'off';
            app.ShutResms.Enable = 'off';
            app.OpenResms.Enable = 'off';
            app.ResolutionSwitch.Enable = 'off';
            app.ConcatenatedSwitch.Enable = 'off';
            app.ClearIdealized.Enable = 'off';
            app.LoadModel.Enable = 'off';
            app.FitButton.Enable = 'off';
            app.ViewModel.Enable = 'off';
            app.RndModel.Enable = 'off';
            app.ClearModel.Enable = 'off';
            app.SaveResultsBtn.Enable = 'off';
            
            % TODO: create a dialog box that contains a text area for displaying the iterations
            fit_prg_dlg = uifigure('Name', 'Fitting Progress', ...
                                   'Tag', 'Fit Progress Dialog', ...
                                   'Position', [100, 100, 500, 500]);
            txa = uitextarea(fit_prg_dlg, ...
                             'Position', [50, 50, 400, 400], ...
                             'Tag', 'Fit Progress Text', ...
                             'Editable', 'off', ...
                             'Value', {'Iteration, -LL, FuncCount, First-order Opt'});
            
            % Fit without tcrit
            tic;
            FITSUCCESS = true;
            try
                [app.fittedRates, app.LL, app.qnew, app.hessian, app.covarmat, app.corrmat, app.history] = ...
                    hjcfit_20180130(app.resolvedDwells, app.q, app.A, app.F, ...
                                    app.OpenResms.Value, app.idxall, app.idxvary, app.gamma, app.xi, ...
                                    'hFig', [app.OpenAxes, app.ShutAxes], 'txtArea', txa);
            catch ME
                msg = sprintf(['There was an error running the model (see below for details).\n', ...
                       'Try randomizing the model and re-running.\n\nDetails:\n%s'], ME.message);
                uialert(app.SCFitUIFigure, msg, 'Error fitting model', 'Icon', 'warning');
                FITSUCCESS = false;
            end
            app.runtime = toc;
            
            % Update the model with the new fitted q
            if FITSUCCESS
                app.q = app.qnew;
            end
            
            % Update the user
            app.StatusLabel.Text = "Fitting complete";
            app.StatusLabel.FontColor = prevColor;
            % Un-gray components
            app.LoadIdealizedButton.Enable = 'on';
            app.ShutResms.Enable = 'on';
            app.OpenResms.Enable = 'on';
            app.ResolutionSwitch.Enable = 'on';
            app.ConcatenatedSwitch.Enable = 'on';
            app.ClearIdealized.Enable = 'on';
            app.LoadModel.Enable = 'on';
            app.FitButton.Enable = 'on';
            app.ViewModel.Enable = 'on';
            app.RndModel.Enable = 'on';
            app.ClearModel.Enable = 'on';
            app.SaveResultsBtn.Enable = 'on';
            
            % delete progress panel
            delete(fit_prg_dlg);
        end

        % Button pushed function: ViewModel
        function ViewModelButtonPushed(app, event)
            if ~isempty(app.q)
                % View the model
                view(biograph(app.q, [], 'layouttype', 'equilibrium'));
            else
                uialert(app.SCFitUIFigure, 'There are no states in the model. Try the ''Load Model'' button.', 'Empty model', 'Icon', 'info');
            end
        end

        % Button pushed function: RndModel
        function RndModelButtonPushed(app, event)
            if ~isempty(app.q)
                % Randomize rates in the following way
                % multiply the log-transformed rates by a random number from the normal distribution
                % with mean 1 and sd 0.03, which should be +/- 10%
                % Then untransform the rates
                app.nRnd = app.nRnd + 1;
                app.q(app.idxall) = exp(log(app.q(app.idxall)) .* (1 + 0.03 * app.nRnd * randn(numel(app.idxall), 1)));
                
                % Show the pdf, if there are resolved dwells
                if ~isempty(app.resolvedDwells)
                    qout = app.q;
                    qout = qout - diag(sum(qout,2));
                    try
                        plotqhist (qout, app.A, app.F, app.resolvedDwells(app.resolvedStates ~= 0), app.resolvedDwells(app.resolvedStates == 0), app.OpenResms.Value);
                    catch ME
                        uialert(app.SCFitUIFigure, 'Error with the new randomized rates', 'Randomize Error', 'Icon', 'warning');
                    end
                end
            else
                uialert(app.SCFitUIFigure, 'There are no states in the model. Try the ''Load Model'' button.', 'Empty model', 'Icon', 'info');
            end
        end

        % Button pushed function: SaveResultsBtn
        function SaveResultsBtnButtonPushed(app, event)
            if isempty(app.qnew)
                uialert(app.SCFitUIFigure, 'There does not seem to be any fit results. Try re-fitting the model.', 'No Fit Results', 'Icon', 'warning');
                return
            end
            eq_Po = eqoccupy(app.qnew);
            [opentaus, openareas, shuttaus, shutareas] = qmatopenshutpdf(app.qnew, app.A, app.F);
            
            [~, datafileonly, ~] = fileparts(app.DataFile.Value);
            [~, modelfileonly, ~] = fileparts(app.ModelFile.Value);
            outfile = sprintf('SCFit_%s_%s_%s.csv', datafileonly, modelfileonly, datestr(datetime, 'yyyymmdd-HHMMSS'));
            [fname, fpath] = uiputfile(outfile, 'Save SCFit Results');
            outfile = [fpath, fname];
            if fname ~= 0
                write_qmatrix_results(app.ModelFile.Value, app.DataFile.Value, app.qnew, app.A, app.F, app.idxall, app.idxvary, ...
                    app.OpenResms.Value, NaN, numel(app.resolvedDwells), app.runtime, app.LL, eq_Po, opentaus, openareas, shuttaus, shutareas, outfile);
            else
                uialert(app.SCFitUIFigure, 'Results not saved', 'No File Selected');
            end
        end

        % Button pushed function: ClearIdealized
        function ClearIdealizedButtonPushed(app, event)
            app.amplitudes = [];
            app.durations = [];
            app.resolvedDwells = [];
            app.resolvedStates = [];
            app.DWTFILE = [];
            app.DataFile.Value = '';
            
            cla(app.OpenAxes, 'reset');
            cla(app.ShutAxes, 'reset');
        end

        % Button pushed function: ClearModel
        function ClearModelButtonPushed(app, event)
            app.q = [];
            app.qnew = [];
            app.fittedRates = [];
            app.LL = [];
            app.hessian = [];
            app.covarmat = [];
            app.corrmat = [];
            app.history = [];
            app.A = [];
            app.F = [];
            app.idxall = [];
            app.gamma = [];
            app.xi = [];
            app.idxvary = [];
            app.idxconstrain = [];
            app.runtime = [];
            app.ModelFile.Value = '';
            app.nRnd = 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SCFitUIFigure = uifigure('Visible', 'off');
            app.SCFitUIFigure.Name = 'SCFit';
            app.OpenAxes = uiaxes(app.SCFitUIFigure);
            title(app.OpenAxes, 'Openings')
            xlabel(app.OpenAxes, 'Time (ms)')
            ylabel(app.OpenAxes, 'Count')
            app.ShutAxes = uiaxes(app.SCFitUIFigure);
            title(app.ShutAxes, 'Closings')
            xlabel(app.ShutAxes, 'Time (ms)')
            ylabel(app.ShutAxes, 'Count')
            app.LoadandPreprocessDataPanel = uipanel(app.SCFitUIFigure);
            app.LoadandPreprocessDataPanel.Title = 'Load and Pre-process Data';
            app.LoadIdealizedButton = uibutton(app.LoadandPreprocessDataPanel, 'push');
            app.LoadIdealizedButton.ButtonPushedFcn = createCallbackFcn(app, @LoadIdealizedButtonPushed, true);
            app.LoadIdealizedButton.Text = 'Load Idealized';
            app.ResolutionsPanel = uipanel(app.LoadandPreprocessDataPanel);
            app.ResolutionsPanel.Title = 'Resolutions (ms)';
            app.ShutLabel = uilabel(app.ResolutionsPanel);
            app.ShutLabel.Text = 'Shut';
            app.ShutResms = uieditfield(app.ResolutionsPanel, 'numeric');
            app.ShutResms.Editable = 'off';
            app.OpenLabel = uilabel(app.ResolutionsPanel);
            app.OpenLabel.Text = 'Open';
            app.OpenResms = uieditfield(app.ResolutionsPanel, 'numeric');
            app.OpenResms.ValueChangedFcn = createCallbackFcn(app, @OpenResmsValueChanged, true);
            app.ResolutionSwitch = uiswitch(app.ResolutionsPanel, 'slider');
            app.ResolutionSwitch.ValueChangedFcn = createCallbackFcn(app, @ImposeResButtonPushed, true);
            app.ConcatenateDwellsPanel = uipanel(app.LoadandPreprocessDataPanel);
            app.ConcatenateDwellsPanel.Title = 'Concatenate Dwells';
            app.ConcatenatedSwitch = uiswitch(app.ConcatenateDwellsPanel, 'slider');
            app.ConcatenatedSwitch.ValueChangedFcn = createCallbackFcn(app, @ConcatenateButtonPushed, true);
            app.ConcatenatedSwitch.Enable = 'off';
            app.ClearIdealized = uibutton(app.LoadandPreprocessDataPanel, 'push');
            app.ClearIdealized.ButtonPushedFcn = createCallbackFcn(app, @ClearIdealizedButtonPushed, true);
            app.ClearIdealized.Text = 'Clear Idealized';
            app.LoadandFitModelPanel = uipanel(app.SCFitUIFigure);
            app.LoadandFitModelPanel.Title = 'Load and Fit Model';
            app.LoadModel = uibutton(app.LoadandFitModelPanel, 'push');
            app.LoadModel.ButtonPushedFcn = createCallbackFcn(app, @LoadModelButtonPushed, true);
            app.LoadModel.Text = 'Load Model';
            app.FitButton = uibutton(app.LoadandFitModelPanel, 'push');
            app.FitButton.ButtonPushedFcn = createCallbackFcn(app, @FitButtonPushed, true);
            app.FitButton.Text = 'Fit';
            app.ViewModel = uibutton(app.LoadandFitModelPanel, 'push');
            app.ViewModel.ButtonPushedFcn = createCallbackFcn(app, @ViewModelButtonPushed, true);
            app.ViewModel.Text = 'View Model';
            app.RndModel = uibutton(app.LoadandFitModelPanel, 'push');
            app.RndModel.ButtonPushedFcn = createCallbackFcn(app, @RndModelButtonPushed, true);
            app.RndModel.Text = 'Randomize Model';
            app.ClearModel = uibutton(app.LoadandFitModelPanel, 'push');
            app.ClearModel.ButtonPushedFcn = createCallbackFcn(app, @ClearModelButtonPushed, true);
            app.ClearModel.Text = 'Clear Model';
            app.DataFileLabel = uilabel(app.SCFitUIFigure);
            app.DataFileLabel.Text = 'Data File:';
            app.DataFile = uieditfield(app.SCFitUIFigure, 'text');
            app.DataFile.Editable = 'off';
            app.DataFile.Value = '<FILENAME>';
            app.StatusPanel = uipanel(app.SCFitUIFigure);
            app.StatusPanel.Title = 'Status';
            app.StatusLabel = uilabel(app.StatusPanel);
            app.StatusLabel.Text = 'Ready';
            app.SaveResultsBtn = uibutton(app.StatusPanel, 'push');
            app.SaveResultsBtn.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsBtnButtonPushed, true);
            app.SaveResultsBtn.Text = 'Save Fitting Results';
            app.ModelFileLabel = uilabel(app.SCFitUIFigure);
            app.ModelFileLabel.Text = 'Model File:';
            app.ModelFile = uieditfield(app.SCFitUIFigure, 'text');
            app.ModelFile.Editable = 'off';
            app.ModelFile.Value = '<FILENAME>';
            app.IftimefreecsventerthesampleintervalEditFieldLabel = uilabel(app.SCFitUIFigure);
            app.IftimefreecsventerthesampleintervalEditFieldLabel.Text = 'If time free csv, enter the sample interval';
            app.si = uieditfield(app.SCFitUIFigure, 'numeric');
            app.si.Limits = [0 Inf];
            app.si.Value = 0.01;
            % Show the figure after all components are created
            app.SCFitUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SCFit

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SCFitUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SCFitUIFigure)
        end
    end
end